classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ParametersTab                matlab.ui.container.Tab
        GapEditFieldLabel            matlab.ui.control.Label
        GapEditField                 matlab.ui.control.NumericEditField
        MismatchEditFieldLabel       matlab.ui.control.Label
        MismatchEditField            matlab.ui.control.NumericEditField
        MatchEditFieldLabel          matlab.ui.control.Label
        MatchEditField               matlab.ui.control.NumericEditField
        ChoosesubMatrixButton        matlab.ui.control.Button
        WindowsizeEditFieldLabel     matlab.ui.control.Label
        WindowsizeEditField          matlab.ui.control.NumericEditField
        TresholdEditFieldLabel       matlab.ui.control.Label
        TresholdEditField            matlab.ui.control.NumericEditField
        TabGroup2                    matlab.ui.container.TabGroup
        Sequence1Tab                 matlab.ui.container.Tab
        LoadsequenceButton           matlab.ui.control.StateButton
        ButtonGroup                  matlab.ui.container.ButtonGroup
        Button_4                     matlab.ui.control.RadioButton
        Button_5                     matlab.ui.control.RadioButton
        Button_6                     matlab.ui.control.RadioButton
        LoadfromfileButton           matlab.ui.control.StateButton
        DatabasetagEditFieldLabel    matlab.ui.control.Label
        DatabasetagEditField         matlab.ui.control.EditField
        Sequence1EditFieldLabel      matlab.ui.control.Label
        Sequence1EditField           matlab.ui.control.EditField
        Sequence2Tab                 matlab.ui.container.Tab
        LoadsequenceButton2          matlab.ui.control.StateButton
        ButtonGroup2                 matlab.ui.container.ButtonGroup
        Button                       matlab.ui.control.RadioButton
        Button_2                     matlab.ui.control.RadioButton
        Button_3                     matlab.ui.control.RadioButton
        Sequence2Label               matlab.ui.control.Label
        Sequence2EditField           matlab.ui.control.EditField
        LoadfromfileButton2          matlab.ui.control.StateButton
        DatabasetagEditField_2Label  matlab.ui.control.Label
        DatabasetagEditField2        matlab.ui.control.EditField
        Sequence1LampLabel           matlab.ui.control.Label
        Sequence1Lamp                matlab.ui.control.Lamp
        Sequence2LampLabel           matlab.ui.control.Label
        Sequence2Lamp                matlab.ui.control.Lamp
        DotplotTab                   matlab.ui.container.Tab
        GlobalaligmentTab            matlab.ui.container.Tab
        LocalaligmentTab             matlab.ui.container.Tab
    end

    methods (Access = private)

        % Selection changed function: ButtonGroup2
        function ButtonGroup2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup2.SelectedObject;
            switch selectedButton
                    case app.Button
                        set(app.LoadfromfileButton2,'Enable','off');
                        set(app.DatabasetagEditField2, 'Enable','off');
                        set(app.Sequence2EditField, 'Enable','on');
                    case app.Button_2
                        set(app.LoadfromfileButton2,'Enable','on');
                        set(app.DatabasetagEditField2, 'Enable','off');
                        set(app.Sequence2EditField, 'Enable','off');
                    case app.Button_3
                        set(app.LoadfromfileButton2,'Enable','off');
                        set(app.DatabasetagEditField2, 'Enable','on');
                        set(app.Sequence2EditField, 'Enable','off');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GapEditFieldLabel = uilabel(app.ParametersTab);
            app.GapEditFieldLabel.Text = 'Gap';
            app.GapEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MismatchEditFieldLabel = uilabel(app.ParametersTab);
            app.MismatchEditFieldLabel.Text = 'Mismatch';
            app.MismatchEditField = uieditfield(app.ParametersTab, 'numeric');
            app.MatchEditFieldLabel = uilabel(app.ParametersTab);
            app.MatchEditFieldLabel.Text = 'Match';
            app.MatchEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ChoosesubMatrixButton = uibutton(app.ParametersTab, 'push');
            app.ChoosesubMatrixButton.Text = 'Choose subMatrix';
            app.WindowsizeEditFieldLabel = uilabel(app.ParametersTab);
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.ParametersTab, 'numeric');
            app.TresholdEditFieldLabel = uilabel(app.ParametersTab);
            app.TresholdEditFieldLabel.Text = 'Treshold';
            app.TresholdEditField = uieditfield(app.ParametersTab, 'numeric');
            app.TabGroup2 = uitabgroup(app.ParametersTab);
            app.Sequence1Tab = uitab(app.TabGroup2);
            app.Sequence1Tab.Title = 'Sequence 1';
            app.LoadsequenceButton = uibutton(app.Sequence1Tab, 'state');
            app.LoadsequenceButton.Text = 'Load sequence';
            app.ButtonGroup = uibuttongroup(app.Sequence1Tab);
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '';
            app.Button_6 = uiradiobutton(app.ButtonGroup);
            app.Button_6.Text = '';
            app.LoadfromfileButton = uibutton(app.ButtonGroup, 'state');
            app.LoadfromfileButton.Text = 'Load from file';
            app.DatabasetagEditFieldLabel = uilabel(app.ButtonGroup);
            app.DatabasetagEditFieldLabel.Text = 'Database tag';
            app.DatabasetagEditField = uieditfield(app.ButtonGroup, 'text');
            app.Sequence1EditFieldLabel = uilabel(app.ButtonGroup);
            app.Sequence1EditFieldLabel.Text = 'Sequence 1';
            app.Sequence1EditField = uieditfield(app.ButtonGroup, 'text');
            app.Sequence2Tab = uitab(app.TabGroup2);
            app.Sequence2Tab.Title = 'Sequence 2';
            app.LoadsequenceButton2 = uibutton(app.Sequence2Tab, 'state');
            app.LoadsequenceButton2.Text = 'Load sequence';
            app.ButtonGroup2 = uibuttongroup(app.Sequence2Tab);
            app.ButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup2SelectionChanged, true);
            app.Button = uiradiobutton(app.ButtonGroup2);
            app.Button.Text = '';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup2);
            app.Button_2.Text = '';
            app.Button_3 = uiradiobutton(app.ButtonGroup2);
            app.Button_3.Text = '';
            app.Sequence2Label = uilabel(app.ButtonGroup2);
            app.Sequence2Label.Text = 'Sequence 2';
            app.Sequence2EditField = uieditfield(app.ButtonGroup2, 'text');
            app.LoadfromfileButton2 = uibutton(app.ButtonGroup2, 'state');
            app.LoadfromfileButton2.Enable = 'off';
            app.LoadfromfileButton2.Text = 'Load from file';
            app.DatabasetagEditField_2Label = uilabel(app.ButtonGroup2);
            app.DatabasetagEditField_2Label.Text = 'Database tag';
            app.DatabasetagEditField2 = uieditfield(app.ButtonGroup2, 'text');
            app.DatabasetagEditField2.Enable = 'off';
            app.Sequence1LampLabel = uilabel(app.ParametersTab);
            app.Sequence1LampLabel.Text = 'Sequence 1';
            app.Sequence1Lamp = uilamp(app.ParametersTab);
            app.Sequence2LampLabel = uilabel(app.ParametersTab);
            app.Sequence2LampLabel.Text = 'Sequence 2';
            app.Sequence2Lamp = uilamp(app.ParametersTab);
            app.DotplotTab = uitab(app.TabGroup);
            app.DotplotTab.Title = 'Dotplot';
            app.GlobalaligmentTab = uitab(app.TabGroup);
            app.GlobalaligmentTab.Title = 'Global aligment';
            app.LocalaligmentTab = uitab(app.TabGroup);
            app.LocalaligmentTab.Title = 'Local aligment';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end